classdef pop_plotdata < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure     matlab.ui.Figure
        GridLayout   matlab.ui.container.GridLayout
        GridLayout2  matlab.ui.container.GridLayout
        CloseButton  matlab.ui.control.Button
        UIAxes       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data
    end
    
    methods (Access = private)
        
        function plot_data(app)
            % Clear the existing axes
            cla(app.UIAxes);
            
            % Calculate the baseline value
            baseline = min(app.data) - 1;
            
            % Plot the data as a stem plot with a custom baseline
            stem(app.UIAxes, app.data, 'filled', 'MarkerSize', 5, 'BaseValue', baseline);
            
            % Customize plot appearance
            title(app.UIAxes, 'LTE Signal Data'); % Add a title
            xlabel(app.UIAxes, 'Sample Index'); % Label for x-axis
            ylabel(app.UIAxes, 'Data Value'); % Label for y-axis
            
            % Set y-axis ticks to integer values only
            yticks(app.UIAxes, floor(min(app.data)):1:ceil(max(app.data)));
            
            % Add grid lines
            grid(app.UIAxes, 'on');
            
            % Adjust font size for better readability
            set(app.UIAxes, 'FontSize', 12);
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, data)
            app.data = data;
            app.plot_data();
        end

        % Button pushed function: CloseButton
        function cb_close(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.UIAxes = uiaxes(app.GridLayout);
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLimitMethod = 'padded';
            app.UIAxes.YLimitMethod = 'padded';
            app.GridLayout2 = uigridlayout(app.GridLayout);
            app.CloseButton = uibutton(app.GridLayout2, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @cb_close, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = pop_plotdata(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end