classdef ltelab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LTELabLTESimulatorAppUIFigure  matlab.ui.Figure
        FileMenu                       matlab.ui.container.Menu
        ImportdataMenu                 matlab.ui.container.Menu
        MatlabArrayMenu                matlab.ui.container.Menu
        AsciifloatfileMenu             matlab.ui.container.Menu
        RandomdataMenu                 matlab.ui.container.Menu
        ExportdataMenu                 matlab.ui.container.Menu
        LoadexistingsetMenu            matlab.ui.container.Menu
        QuitMenu                       matlab.ui.container.Menu
        EditMenu                       matlab.ui.container.Menu
        ToolsMenu                      matlab.ui.container.Menu
        modulationMenu                 matlab.ui.container.Menu
        SetmodulatordemodulatorMenu_2  matlab.ui.container.Menu
        modulatesignalMenu             matlab.ui.container.Menu
        demodulatesignalMenu           matlab.ui.container.Menu
        transmitionchannelMenu         matlab.ui.container.Menu
        AddremovechannelMenu           matlab.ui.container.Menu
        ApplychannelMenu               matlab.ui.container.Menu
        PlotMenu                       matlab.ui.container.Menu
        PlotdataMenu                   matlab.ui.container.Menu
        PlotsignalMenu                 matlab.ui.container.Menu
        TimeFrequencytransformsMenu    matlab.ui.container.Menu
        DatasetsMenu                   matlab.ui.container.Menu
        HelpMenu                       matlab.ui.container.Menu
        AboutLTELabMenu                matlab.ui.container.Menu
        LTELabTutorialMenu             matlab.ui.container.Menu
        EmailtheLTELabteamMenu         matlab.ui.container.Menu
        GridLayout                     matlab.ui.container.GridLayout
        ModulationOrderNumLabel        matlab.ui.control.Label
        ModulationorderLabel           matlab.ui.control.Label
        ModulatorLabel_3               matlab.ui.control.Label
        SignalLengthNumLabel           matlab.ui.control.Label
        SignallengthmsLabel            matlab.ui.control.Label
        GridLayout_2                   matlab.ui.container.GridLayout
        FileSetNameLabel_2             matlab.ui.control.Label
        FilenameLabel_2                matlab.ui.control.Label
        SamplingRateNumLabel_2         matlab.ui.control.Label
        SamplingrateLabel_2            matlab.ui.control.Label
        ChannelSetLabel_2              matlab.ui.control.Label
        ChannelLabel_2                 matlab.ui.control.Label
        ModulatorNameLabel_2           matlab.ui.control.Label
        ModulatorLabel_2               matlab.ui.control.Label
        FramesNumLabel_2               matlab.ui.control.Label
        FramesLabel_2                  matlab.ui.control.Label
        SourceNumLabel_2               matlab.ui.control.Label
        SourcesLabel_2                 matlab.ui.control.Label
        NodataloadedLabel_2            matlab.ui.control.Label
        FileSetNameLabel               matlab.ui.control.Label
        FilenameLabel                  matlab.ui.control.Label
        SamplingRateNumLabel           matlab.ui.control.Label
        SamplingrateLabel              matlab.ui.control.Label
        ChannelSetLabel                matlab.ui.control.Label
        ChannelLabel                   matlab.ui.control.Label
        ModulatorNameLabel             matlab.ui.control.Label
        FramesNumLabel                 matlab.ui.control.Label
        FramesLabel                    matlab.ui.control.Label
        SourceNumLabel                 matlab.ui.control.Label
        SourcesLabel                   matlab.ui.control.Label
        NodataloadedLabel              matlab.ui.control.Label
    end

    
    properties (Access = public)
        LTE = LTE_dataframe();
    end
    
    properties (Access = private)
        DialogApp % Any secondary dialog being called
    end
    
    methods (Access = public)
        
        function updateLTE(app, LTE)
            app.LTE = LTE;
            app.redrawData();
        end

        function redrawData(app)
             app.NodataloadedLabel.Text = app.LTE.name;
             app.SourceNumLabel.Text = sprintf("%d" ,app.LTE.numchannels);
             frames = size(app.LTE.data,1);
             app.FramesNumLabel.Text = sprintf("%d", frames);
             app.ModulatorNameLabel.Text = ''; % TODO
             app.ModulationOrderNumLabel.Text = sprintf("%d", ...
                 app.LTE.modulationOrder);
             channelSet = 'Yes';
             if isnan(app.LTE.channel)
                 channelSet = 'No';
             end
             app.ChannelSetLabel.Text = channelSet;
             app.SignalLengthNumLabel.Text = sprintf("%.2f",...
                 1000 * frames / app.LTE.fs);
             app.SamplingRateNumLabel.Text = sprintf("%d",app.LTE.fs);
             app.FileSetNameLabel.Text = app.LTE.path;
        end

        function setToolbarState(app, edit, Tools, Plot)
            app.EditMenu.Enable = edit;
            app.ToolsMenu.Enable = Tools;
            app.PlotMenu.Enable = Plot;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Menu selected function: RandomdataMenu
        function cb_importrandomdata(app, event)
            app.DialogApp = pop_importrandomdata(app, app.LTE);
        end

        % Menu selected function: QuitMenu
        function cb_quit(app, event)
            delete(app.DialogApp);
            delete(app);
        end

        % Menu selected function: PlotdataMenu
        function cb_plotdata(app, event)
            app.DialogApp = pop_plotdata(app.LTE.data);
        end

        % Menu selected function: ExportdataMenu
        function cb_export(app, event)
            assignin("base","lte",app.LTE);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LTELabLTESimulatorAppUIFigure = uifigure('Visible', 'off');
            app.LTELabLTESimulatorAppUIFigure.Name = 'LTELab - LTE Simulator App';
            app.FileMenu = uimenu(app.LTELabLTESimulatorAppUIFigure);
            app.FileMenu.Text = 'File';
            app.ImportdataMenu = uimenu(app.FileMenu);
            app.ImportdataMenu.Text = 'Import data';
            app.MatlabArrayMenu = uimenu(app.ImportdataMenu);
            app.MatlabArrayMenu.Text = 'Matlab Array';
            app.AsciifloatfileMenu = uimenu(app.ImportdataMenu);
            app.AsciifloatfileMenu.Text = 'Ascii/float file';
            app.RandomdataMenu = uimenu(app.ImportdataMenu);
            app.RandomdataMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_importrandomdata, true);
            app.RandomdataMenu.Text = 'Random data';
            app.ExportdataMenu = uimenu(app.FileMenu);
            app.ExportdataMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_export, true);
            app.ExportdataMenu.Text = 'Export data';
            app.LoadexistingsetMenu = uimenu(app.FileMenu);
            app.LoadexistingsetMenu.Text = 'Load existing set';
            app.QuitMenu = uimenu(app.FileMenu);
            app.QuitMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_quit, true);
            app.QuitMenu.Text = 'Quit';
            app.EditMenu = uimenu(app.LTELabLTESimulatorAppUIFigure);
            app.EditMenu.Enable = 'off';
            app.EditMenu.Text = 'Edit';
            app.ToolsMenu = uimenu(app.LTELabLTESimulatorAppUIFigure);
            app.ToolsMenu.Enable = 'off';
            app.ToolsMenu.Text = 'Tools';
            app.modulationMenu = uimenu(app.ToolsMenu);
            app.modulationMenu.Text = 'modulation';
            app.SetmodulatordemodulatorMenu_2 = uimenu(app.modulationMenu);
            app.SetmodulatordemodulatorMenu_2.Text = 'Set modulator/demodulator';
            app.modulatesignalMenu = uimenu(app.modulationMenu);
            app.modulatesignalMenu.Text = 'modulate signal';
            app.demodulatesignalMenu = uimenu(app.modulationMenu);
            app.demodulatesignalMenu.Text = 'demodulate signal';
            app.transmitionchannelMenu = uimenu(app.ToolsMenu);
            app.transmitionchannelMenu.Text = 'transmition channel';
            app.AddremovechannelMenu = uimenu(app.transmitionchannelMenu);
            app.AddremovechannelMenu.Text = 'Add/remove channel';
            app.ApplychannelMenu = uimenu(app.transmitionchannelMenu);
            app.ApplychannelMenu.Text = 'Apply channel';
            app.PlotMenu = uimenu(app.LTELabLTESimulatorAppUIFigure);
            app.PlotMenu.Enable = 'off';
            app.PlotMenu.Text = 'Plot';
            app.PlotdataMenu = uimenu(app.PlotMenu);
            app.PlotdataMenu.MenuSelectedFcn = createCallbackFcn(app, @cb_plotdata, true);
            app.PlotdataMenu.Text = 'Plot data';
            app.PlotsignalMenu = uimenu(app.PlotMenu);
            app.PlotsignalMenu.Text = 'Plot signal';
            app.TimeFrequencytransformsMenu = uimenu(app.PlotMenu);
            app.TimeFrequencytransformsMenu.Text = 'Time/Frequency transforms';
            app.DatasetsMenu = uimenu(app.LTELabLTESimulatorAppUIFigure);
            app.DatasetsMenu.Text = 'Datasets';
            app.HelpMenu = uimenu(app.LTELabLTESimulatorAppUIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutLTELabMenu = uimenu(app.HelpMenu);
            app.AboutLTELabMenu.Text = 'About LTELab';
            app.LTELabTutorialMenu = uimenu(app.HelpMenu);
            app.LTELabTutorialMenu.Text = 'LTELab Tutorial';
            app.EmailtheLTELabteamMenu = uimenu(app.HelpMenu);
            app.EmailtheLTELabteamMenu.Text = 'Email the LTELab team';
            app.GridLayout = uigridlayout(app.LTELabLTESimulatorAppUIFigure);
            app.NodataloadedLabel = uilabel(app.GridLayout);
            app.NodataloadedLabel.Text = 'No data loaded';
            app.SourcesLabel = uilabel(app.GridLayout);
            app.SourcesLabel.Text = 'Sources';
            app.SourceNumLabel = uilabel(app.GridLayout);
            app.SourceNumLabel.Text = 'SourceNum';
            app.FramesLabel = uilabel(app.GridLayout);
            app.FramesLabel.Text = 'Frames';
            app.FramesNumLabel = uilabel(app.GridLayout);
            app.FramesNumLabel.Text = 'FramesNum';
            app.ModulatorNameLabel = uilabel(app.GridLayout);
            app.ModulatorNameLabel.Text = 'ModulatorName';
            app.ChannelLabel = uilabel(app.GridLayout);
            app.ChannelLabel.Text = 'Channel';
            app.ChannelSetLabel = uilabel(app.GridLayout);
            app.ChannelSetLabel.Text = 'ChannelSet';
            app.SamplingrateLabel = uilabel(app.GridLayout);
            app.SamplingrateLabel.Text = 'Sampling rate';
            app.SamplingRateNumLabel = uilabel(app.GridLayout);
            app.SamplingRateNumLabel.Text = 'SamplingRateNum';
            app.FilenameLabel = uilabel(app.GridLayout);
            app.FilenameLabel.Text = 'File name';
            app.FileSetNameLabel = uilabel(app.GridLayout);
            app.FileSetNameLabel.Text = 'FileSetName';
            app.GridLayout_2 = uigridlayout(app.GridLayout);
            app.NodataloadedLabel_2 = uilabel(app.GridLayout_2);
            app.NodataloadedLabel_2.Text = 'No data loaded';
            app.SourcesLabel_2 = uilabel(app.GridLayout_2);
            app.SourcesLabel_2.Text = 'Sources';
            app.SourceNumLabel_2 = uilabel(app.GridLayout_2);
            app.SourceNumLabel_2.Text = 'SourceNum';
            app.FramesLabel_2 = uilabel(app.GridLayout_2);
            app.FramesLabel_2.Text = 'Frames';
            app.FramesNumLabel_2 = uilabel(app.GridLayout_2);
            app.FramesNumLabel_2.Text = 'FramesNum';
            app.ModulatorLabel_2 = uilabel(app.GridLayout_2);
            app.ModulatorLabel_2.Text = 'Modulator';
            app.ModulatorNameLabel_2 = uilabel(app.GridLayout_2);
            app.ModulatorNameLabel_2.Text = 'ModulatorName';
            app.ChannelLabel_2 = uilabel(app.GridLayout_2);
            app.ChannelLabel_2.Text = 'Channel';
            app.ChannelSetLabel_2 = uilabel(app.GridLayout_2);
            app.ChannelSetLabel_2.Text = 'ChannelSet';
            app.SamplingrateLabel_2 = uilabel(app.GridLayout_2);
            app.SamplingrateLabel_2.Text = 'Sampling rate';
            app.SamplingRateNumLabel_2 = uilabel(app.GridLayout_2);
            app.SamplingRateNumLabel_2.Text = 'SamplingRateNum';
            app.FilenameLabel_2 = uilabel(app.GridLayout_2);
            app.FilenameLabel_2.Text = 'File name';
            app.FileSetNameLabel_2 = uilabel(app.GridLayout_2);
            app.FileSetNameLabel_2.Text = 'FileSetName';
            app.SignallengthmsLabel = uilabel(app.GridLayout);
            app.SignallengthmsLabel.Text = 'Signal length (ms)';
            app.SignalLengthNumLabel = uilabel(app.GridLayout);
            app.SignalLengthNumLabel.Text = 'SignalLengthNum';
            app.ModulatorLabel_3 = uilabel(app.GridLayout);
            app.ModulatorLabel_3.Text = 'Modulator';
            app.ModulationorderLabel = uilabel(app.GridLayout);
            app.ModulationorderLabel.Text = 'Modulation order';
            app.ModulationOrderNumLabel = uilabel(app.GridLayout);
            app.ModulationOrderNumLabel.Text = 'ModulationOrderNum';
            % Show the figure after all components are created
            app.LTELabLTESimulatorAppUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ltelab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LTELabLTESimulatorAppUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LTELabLTESimulatorAppUIFigure)
        end
    end
end